classdef apppendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        timeEditField          matlab.ui.control.NumericEditField
        timeEditFieldLabel     matlab.ui.control.Label
        wEditField             matlab.ui.control.NumericEditField
        wEditFieldLabel        matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        simulateButton         matlab.ui.control.Button
        Panel2                 matlab.ui.container.Panel
        xCheckBox              matlab.ui.control.CheckBox
        sCheckBox              matlab.ui.control.CheckBox
        thCheckBox             matlab.ui.control.CheckBox
        UIAxes2                matlab.ui.control.UIAxes
        Panel                  matlab.ui.container.Panel
        dxCheckBox             matlab.ui.control.CheckBox
        dsCheckBox             matlab.ui.control.CheckBox
        dthCheckBox            matlab.ui.control.CheckBox
        UIAxes                 matlab.ui.control.UIAxes
        loadvariableButton     matlab.ui.control.Button
        xmEditField            matlab.ui.control.NumericEditField
        xmEditFieldLabel       matlab.ui.control.Label
        dxmsEditField          matlab.ui.control.NumericEditField
        dxmsEditFieldLabel     matlab.ui.control.Label
        smEditField            matlab.ui.control.NumericEditField
        smEditFieldLabel       matlab.ui.control.Label
        thdegEditField         matlab.ui.control.NumericEditField
        thdegEditFieldLabel    matlab.ui.control.Label
        dsmsEditField          matlab.ui.control.NumericEditField
        dsmsEditFieldLabel     matlab.ui.control.Label
        dthradsEditField       matlab.ui.control.NumericEditField
        dthradsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        time; % Description
        velocity;
        position;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadvariableButton
        function loadvariableButtonPushed(app, event)
            dth=app.dthradsEditField.Value;
            th=app.thdegEditField.Value;
            ds=app.dsmsEditField.Value;
            s=app.smEditField.Value;
            dx=app.dxmsEditField.Value;
            x=app.xmEditField.Value;
            w=app.wEditField.Value;
            inits=[dth,th,ds,s,dx,x];
            app.time=app.timeEditField.Value;
            assignin("base","inits",inits);
            assignin("base","w",w);
           
            
        end

        % Button pushed function: simulateButton
        function simulateButtonPushed(app, event)
            mdl="matlabpendulum";
            set_param(mdl,"StopTime",num2str(app.time(1)));
            load_system(mdl);
            simout=sim(mdl);
            dth=simout.z(:,1);
            th=simout.z(:,2);
            ds=simout.z(:,3);
            s=simout.z(:,4);
            dx=simout.z(:,5);
            x=simout.z(:,6);
            
            t=simout.t(:,1);
            
            app.velocity=plot(app.UIAxes,t,dth,t,ds,t,dx,"Visible","off");
            app.position=plot(app.UIAxes2,t,th,t,s,t,x,"Visible","off");
           
        end

        % Value changed function: dthCheckBox
        function dthCheckBoxValueChanged(app, event)
            value = app.dthCheckBox.Value;
            if value==1
                app.velocity(1).Visible="on";
            else
                app.velocity(1).Visible="off";
            end
        end

        % Value changed function: dsCheckBox
        function dsCheckBoxValueChanged(app, event)
            value = app.dsCheckBox.Value;
            if value==1
                app.velocity(2).Visible="on";
            else
                app.velocity(2).Visible="off";
            end
        end

        % Value changed function: dxCheckBox
        function dxCheckBoxValueChanged(app, event)
            value = app.dxCheckBox.Value;
            if value==1
                app.velocity(3).Visible="on";
            else
                app.velocity(3).Visible="off";
            end
        end

        % Value changed function: thCheckBox
        function thCheckBoxValueChanged(app, event)
            value = app.thCheckBox.Value;
            if value==1
                app.position(1).Visible="on";
            else
                app.position(1).Visible="off";
            end
        end

        % Value changed function: sCheckBox
        function sCheckBoxValueChanged(app, event)
            value = app.sCheckBox.Value;
              if value==1
                app.position(2).Visible="on";
            else
                app.position(2).Visible="off";
            end
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            value = app.xCheckBox.Value;
            if value==1
                app.position(3).Visible="on";
            else
                app.position(3).Visible="off";
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.wEditField.Value=changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dthradsEditFieldLabel = uilabel(app.UIFigure);
            app.dthradsEditFieldLabel.Text = 'dth rad/s ';
            app.dthradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dsmsEditFieldLabel = uilabel(app.UIFigure);
            app.dsmsEditFieldLabel.Text = 'ds m/s';
            app.dsmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.thdegEditFieldLabel = uilabel(app.UIFigure);
            app.thdegEditFieldLabel.Text = 'th deg';
            app.thdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.smEditFieldLabel = uilabel(app.UIFigure);
            app.smEditFieldLabel.Text = 's m';
            app.smEditField = uieditfield(app.UIFigure, 'numeric');
            app.dxmsEditFieldLabel = uilabel(app.UIFigure);
            app.dxmsEditFieldLabel.Text = 'dx m/s';
            app.dxmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmEditFieldLabel = uilabel(app.UIFigure);
            app.xmEditFieldLabel.Text = 'x m';
            app.xmEditField = uieditfield(app.UIFigure, 'numeric');
            app.loadvariableButton = uibutton(app.UIFigure, 'push');
            app.loadvariableButton.ButtonPushedFcn = createCallbackFcn(app, @loadvariableButtonPushed, true);
            app.loadvariableButton.Text = 'load variable';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.dthCheckBox = uicheckbox(app.Panel);
            app.dthCheckBox.ValueChangedFcn = createCallbackFcn(app, @dthCheckBoxValueChanged, true);
            app.dthCheckBox.Text = 'dth';
            app.dsCheckBox = uicheckbox(app.Panel);
            app.dsCheckBox.ValueChangedFcn = createCallbackFcn(app, @dsCheckBoxValueChanged, true);
            app.dsCheckBox.Text = 'ds';
            app.dxCheckBox = uicheckbox(app.Panel);
            app.dxCheckBox.ValueChangedFcn = createCallbackFcn(app, @dxCheckBoxValueChanged, true);
            app.dxCheckBox.Text = 'dx';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes2 = uiaxes(app.Panel2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.thCheckBox = uicheckbox(app.Panel2);
            app.thCheckBox.ValueChangedFcn = createCallbackFcn(app, @thCheckBoxValueChanged, true);
            app.thCheckBox.Text = 'th';
            app.sCheckBox = uicheckbox(app.Panel2);
            app.sCheckBox.ValueChangedFcn = createCallbackFcn(app, @sCheckBoxValueChanged, true);
            app.sCheckBox.Text = 's';
            app.xCheckBox = uicheckbox(app.Panel2);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.simulateButton = uibutton(app.UIFigure, 'push');
            app.simulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulateButton.Text = 'simulate';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.wEditFieldLabel = uilabel(app.UIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apppendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end